classdef ARSS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBMUIFigure       matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        PanelA            matlab.ui.container.Panel
        PanelA1           matlab.ui.container.Panel
        ListBox           matlab.ui.control.ListBox
        PanelA2           matlab.ui.container.Panel
        BrowseButton      matlab.ui.control.Button
        PanelB            matlab.ui.container.Panel
        PanelB1           matlab.ui.container.Panel
        smprateLabel      matlab.ui.control.Label
        smprateDropDown   matlab.ui.control.DropDown
        bitdepthLabel     matlab.ui.control.Label
        bitdepthDropDown  matlab.ui.control.DropDown
        PanelB2           matlab.ui.container.Panel
        lengthLabel       matlab.ui.control.Label
        lengthSpinner     matlab.ui.control.Spinner
        overlapLabel      matlab.ui.control.Label
        overlapSpinner    matlab.ui.control.Spinner
        PanelB3           matlab.ui.container.Panel
        ProcessButton     matlab.ui.control.Button
        PanelC            matlab.ui.container.Panel
        PanelC1           matlab.ui.container.Panel
        widthppsLabel     matlab.ui.control.Label
        widthppsSpinner   matlab.ui.control.Spinner
        heightbpoLabel    matlab.ui.control.Label
        heightbpoSpinner  matlab.ui.control.Spinner
        maxfreqLabel      matlab.ui.control.Label
        maxfreqSpinner    matlab.ui.control.Spinner
        minfreqLabel      matlab.ui.control.Label
        minfreqSpinner    matlab.ui.control.Spinner
        PanelC2           matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        AnalyzeButton     matlab.ui.control.Button
        SynthesizeButton  matlab.ui.control.Button
        PanelD            matlab.ui.container.Panel
        PanelD1           matlab.ui.container.Panel
        MsgBox            matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        pr_fileList;
        pr_list;
        pr_inputPath;
        pr_exportPath;
        pr_rsmfl;
        pr_btsps;
        pr_nwspr;
        pr_sgmto;
        pr_sgmtl;
        pr_height;
        pr_mnmmf;
        pr_mxmmf
    end
    
    methods (Access = private)


    end
    
    methods (Access = public)
        
        function preprocess(app)
            
            % initiate progess dialog            
            d = uiprogressdlg(app.DBMUIFigure,'Title','Please Wait','Message','Starting Pre-Processing','Indeterminate','on','Cancelable','on');
            
            % get input path and input file list
            fdiri = app.pr_inputPath;
            fllen = size(app.pr_fileList,  2);
            inflp = strcat(string(fdiri),filesep);
            
            % get new sample rate and bit depth
            nwspr = str2double(erase(string(app.pr_nwspr)," Hz"));
            btdpt = str2double(erase(string(app.pr_btsps)," Bit"));
            
            % get length and offset           
            lngth = str2double(erase(string(app.pr_sgmtl)," s"));
            offst = str2double(erase(string(app.pr_sgmto)," s"));
            lngth = round(lngth * nwspr);
            offst = round(offst * nwspr);

            % create 'resampled'/'segments' folders
            tgflp = strcat(string(fdiri),filesep,"resampled", filesep);
            if exist(tgflp, 'dir')
            else
                mkdir(tgflp);
            end
            sgflp = strcat(string(fdiri),filesep,"segments",filesep);          
            if exist(sgflp, 'dir')
            else
                mkdir(sgflp);
            end
            
            % resample, dither, make mono, save to 'resampled' folder          
            for fileId = 1 : fllen
                
                if d.CancelRequested
                    break
                end    
                iflnm = string(app.pr_fileList(fileId).name);
                tflnm = strcat(inflp,"resampled", filesep, "rsmpld_",iflnm);
                [infla, smplr] = audioread(iflnm);
                mnfla = sum(infla,2);
                [nsprn,nsprd]=rat(nwspr/smplr);
                trfla = resample(mnfla,nsprn,nsprd);
                audiowrite(tflnm,trfla,nwspr,'BitsPerSample',btdpt);
                
            end
            
            % set input path to 'resampled' folder
            app.pr_inputPath = tgflp;
            app.pr_fileList = retrieveFilesAuxFn(app.pr_inputPath,'.wav');
            populateListBoxAuxFn(app);
            fllen = size(app.pr_fileList,  2);
            
            % segment files           
            for fileId = 1 : fllen
                
                if d.CancelRequested
                    break
                end
                iflnm = string(app.pr_fileList(fileId).name);
                tflnm = strcat(inflp,"segments", filesep, iflnm);
                tflnm = erase(string(tflnm),".wav");
                tflnm = erase(string(tflnm),"rsmpld_");
                info = audioinfo(iflnm);
                tsmp = info.TotalSamples;
                cpst = 1; 
                fname = 1;
                nofm = floor((tsmp-lngth)/offst)+1;
                for seg = 1 : nofm
                    data = audioread(iflnm, [cpst cpst+lngth-1]);
                    otfn = strcat(tflnm,"-","SGMNT_",string(fname),".wav");
                    audiowrite(otfn,data,nwspr);
                    cpst=cpst+offst;
                    fname=fname+1;
                end
            
            end
 
            % set input path to 'segmented' folder
            app.pr_inputPath = sgflp;
            app.pr_fileList = retrieveFilesPartsAuxFn(app.pr_inputPath,'.wav');
            populateListBoxPartsAuxFn(app);
            close(d);
            
        end
        
        function arssanalyze(app)
                %f = uifigure;
            d = uiprogressdlg(app.DBMUIFigure,'Title','Please Wait','Message','Opening ARSS','Cancelable','on');
            pause(.1)
            paths = app.pr_inputPath;
            minfreq = app.minfreqSpinner.Value;
            maxfreq = app.maxfreqSpinner.Value;
            heightx = app.heightbpoSpinner.Value;
            widthmx = app.widthppsSpinner.Value;
            listlen = size(app.pr_fileList,  2);
            samplerate = erase(string(app.pr_nwspr)," Hz");
            
            for fileId = 1 : listlen
                if d.CancelRequested
                    break
                end
                anlinpath=strcat(string(paths),filesep,string(app.pr_fileList(fileId).name));
                anloutpath=strcat(string(paths),filesep,erase(string(app.pr_fileList(fileId).name),".wav"),".bmp",{' '});
                cmd = strcat('C:\Users\msft650\Matlab\lib\arss.exe -a',{' '},anlinpath,{' '}, anloutpath,"-min",{' '},string(minfreq),{' '},'-max',{' '},string(maxfreq),{' '},'-y',{' '},string(heightx),{' '},'-x',{' '},string(widthmx),{' '},'-r',{' '}, samplerate);
                system(cmd);
                d.Value = fileId/listlen;
                str = strcat("File",{' '},string(fileId),{' '},"of",{' '},string(listlen),{':'});
                d.Message = str + newline + string(app.pr_fileList(fileId).name)+" To BMP Image";
            end
            
            close(d);
            %close(f);
        end

        
        function arsssynthesize(app)
            [inputPath,exportPath,list] = setFolderPathBMPAuxFn(app);
            if isempty(exportPath)
                app.MsgBox.Value = [app.MsgBox.Value ;...
                    sprintf('%s','Error: no path is selected.')];
            else
                app.pr_inputPath = inputPath;
                app.pr_list = list;
                app.pr_fileList = retrieveFilesBMPAuxFn(app.pr_inputPath,'.bmp');
                populateListBoxBMPAuxFn(app);
            end
            d = uiprogressdlg(app.DBMUIFigure,'Title','Please Wait','Message','Opening ARSS','Cancelable','on');
            pause(.1)
            paths = app.pr_inputPath;
            minfreq = app.minfreqSpinner.Value;
            maxfreq = app.maxfreqSpinner.Value;
            heightx = app.heightbpoSpinner.Value;
            widthmx = app.widthppsSpinner.Value;
            listlen = size(app.pr_fileList,  2);
            samplerate = erase(string(app.pr_nwspr)," Hz");
            bitdepth = erase(string(app.pr_btsps)," Bit");
            for fileId = 1 : listlen
                if d.CancelRequested
                    break
                end
                syninpath=strcat(string(paths),filesep,string(app.pr_fileList(fileId).name));
                synoutpath=strcat(string(paths),filesep,erase(string(app.pr_fileList(fileId).name),".bmp"),".wav",{' '});
                cmd = strcat('C:\Users\msft650\Matlab\lib\arss.exe -n',{' '},syninpath,{' '},synoutpath,"-min",{' '},string(minfreq),{' '},'-max',{' '},string(maxfreq),{' '},'-y',{' '},string(heightx),{' '},'-x',{' '},string(widthmx),{' '},'-r',{' '}, string(samplerate), {' '},'-f',{' '}, string(bitdepth), {' '},'-p',{' '},"100");
                system(cmd);
                d.Value = fileId/listlen;
                str = strcat("File",{' '},string(fileId),{' '},"of",{' '},string(listlen),{':'});
                d.Message = str + newline + string(app.pr_fileList(fileId).name)+" To WAV Sound";
            end     
            close(d);
            %close(f)      
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [inputPath,exportPath,list] = setFolderPathAuxFn(app);
            if isempty(exportPath)
                app.MsgBox.Value = [app.MsgBox.Value ;...
                    sprintf('%s','Error: no path is selected.')];
            else
                app.pr_inputPath = inputPath;
                app.pr_list = list;
                app.pr_fileList = retrieveFilesAuxFn(app.pr_inputPath,'.wav');
                populateListBoxAuxFn(app);
            end
        end

        % Value changed function: overlapSpinner
        function overlapSpinnerValueChanged(app, event)
          app.pr_sgmto = app.overlapSpinner.Value;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            arssanalyze(app);
            
        end

        % Value changed function: heightbpoSpinner
        function heightbpoSpinnerValueChanged(app, event)
           app.pr_height = app.overlapSpinner.Value;
       
            
        end

        % Value changed function: minfreqSpinner
        function minfreqSpinnerValueChanged(app, event)
           app.pr_mnmmf = app.minfreqSpinner.Value;
            
        end

        % Value changed function: maxfreqSpinner
        function maxfreqSpinnerValueChanged(app, event)
            app.pr_mxmmf=app.maxfreqSpinner.Value;
            
        end

        % Value changed function: widthppsSpinner
        function widthppsSpinnerValueChanged(app, event)
           
          app.pr_height = app.widthppsSpinner.Value;


        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            preprocess(app);
        end

        % Value changed function: bitdepthDropDown
        function bitdepthDropDownValueChanged(app, event)
            app.pr_btsps = app.bitdepthDropDown.Value;
            
        end

        % Value changed function: smprateDropDown
        function smprateDropDownValueChanged(app, event)
           app.pr_nwspr = app.smprateDropDown.Value;
            
        end

        % Value changed function: lengthSpinner
        function lengthSpinnerValueChanged(app, event)
           app.pr_sgmtl = app.lengthSpinner.Value;
            
        end

        % Button pushed function: SynthesizeButton
        function SynthesizeButtonPushed(app, event)
            arsssynthesize(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBMUIFigure = uifigure('Visible', 'off');
            app.DBMUIFigure.Name = 'DBM';
            app.Panel = uipanel(app.DBMUIFigure);
            app.PanelA = uipanel(app.Panel);
            app.PanelA.Title = 'File Selection';
            app.PanelA1 = uipanel(app.PanelA);
            app.ListBox = uilistbox(app.PanelA1);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.PanelA2 = uipanel(app.PanelA);
            app.BrowseButton = uibutton(app.PanelA2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PanelB = uipanel(app.Panel);
            app.PanelB.Title = 'Pre-Processing';
            app.PanelB1 = uipanel(app.PanelB);
            app.smprateLabel = uilabel(app.PanelB1);
            app.smprateLabel.Text = 'Smp. Rate';
            app.smprateDropDown = uidropdown(app.PanelB1);
            app.smprateDropDown.Items = {'Select', '8000 Hz', '11025 Hz', '16000 Hz', '22050 Hz', '32000 Hz', '37800 Hz ', '44056 Hz', '44100 Hz', '47250 Hz', '48000 Hz'};
            app.smprateDropDown.ValueChangedFcn = createCallbackFcn(app, @smprateDropDownValueChanged, true);
            app.smprateDropDown.Value = 'Select';
            app.bitdepthLabel = uilabel(app.PanelB1);
            app.bitdepthLabel.Text = 'Bit Depth';
            app.bitdepthDropDown = uidropdown(app.PanelB1);
            app.bitdepthDropDown.Items = {'Select', '4 Bit', '8 Bit', '16 Bit', '32 Bit'};
            app.bitdepthDropDown.ValueChangedFcn = createCallbackFcn(app, @bitdepthDropDownValueChanged, true);
            app.bitdepthDropDown.Value = 'Select';
            app.PanelB2 = uipanel(app.PanelB);
            app.lengthLabel = uilabel(app.PanelB2);
            app.lengthLabel.Text = 'Length';
            app.lengthSpinner = uispinner(app.PanelB2);
            app.lengthSpinner.Step = 0.001;
            app.lengthSpinner.Limits = [0.1 9999];
            app.lengthSpinner.ValueDisplayFormat = '%1.3f s';
            app.lengthSpinner.ValueChangedFcn = createCallbackFcn(app, @lengthSpinnerValueChanged, true);
            app.lengthSpinner.Value = 2;
            app.overlapLabel = uilabel(app.PanelB2);
            app.overlapLabel.Text = 'Offset';
            app.overlapSpinner = uispinner(app.PanelB2);
            app.overlapSpinner.Step = 0.0001;
            app.overlapSpinner.Limits = [0.1 4444];
            app.overlapSpinner.ValueDisplayFormat = '%1.3f s';
            app.overlapSpinner.ValueChangedFcn = createCallbackFcn(app, @overlapSpinnerValueChanged, true);
            app.overlapSpinner.Value = 1;
            app.PanelB3 = uipanel(app.PanelB);
            app.ProcessButton = uibutton(app.PanelB3, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.PanelC = uipanel(app.Panel);
            app.PanelC.Title = 'Analysis & Synthesis';
            app.PanelC1 = uipanel(app.PanelC);
            app.widthppsLabel = uilabel(app.PanelC1);
            app.widthppsLabel.Text = 'Width';
            app.widthppsSpinner = uispinner(app.PanelC1);
            app.widthppsSpinner.Limits = [1 9999];
            app.widthppsSpinner.RoundFractionalValues = 'on';
            app.widthppsSpinner.ValueDisplayFormat = '%i px';
            app.widthppsSpinner.ValueChangedFcn = createCallbackFcn(app, @widthppsSpinnerValueChanged, true);
            app.widthppsSpinner.Value = 2048;
            app.heightbpoLabel = uilabel(app.PanelC1);
            app.heightbpoLabel.Text = 'Height';
            app.heightbpoSpinner = uispinner(app.PanelC1);
            app.heightbpoSpinner.Limits = [1 9999];
            app.heightbpoSpinner.RoundFractionalValues = 'on';
            app.heightbpoSpinner.ValueDisplayFormat = '%i px';
            app.heightbpoSpinner.ValueChangedFcn = createCallbackFcn(app, @heightbpoSpinnerValueChanged, true);
            app.heightbpoSpinner.Value = 2048;
            app.maxfreqLabel = uilabel(app.PanelC1);
            app.maxfreqLabel.Text = 'Max Freq';
            app.maxfreqSpinner = uispinner(app.PanelC1);
            app.maxfreqSpinner.Step = 0.1;
            app.maxfreqSpinner.Limits = [1 20000];
            app.maxfreqSpinner.ValueDisplayFormat = '%1.1f Hz';
            app.maxfreqSpinner.ValueChangedFcn = createCallbackFcn(app, @maxfreqSpinnerValueChanged, true);
            app.maxfreqSpinner.Value = 20000;
            app.minfreqLabel = uilabel(app.PanelC1);
            app.minfreqLabel.Text = 'Min Freq';
            app.minfreqSpinner = uispinner(app.PanelC1);
            app.minfreqSpinner.Step = 0.1;
            app.minfreqSpinner.Limits = [1 20000];
            app.minfreqSpinner.ValueDisplayFormat = '%1.1f Hz';
            app.minfreqSpinner.ValueChangedFcn = createCallbackFcn(app, @minfreqSpinnerValueChanged, true);
            app.minfreqSpinner.Value = 27.5;
            app.PanelC2 = uipanel(app.PanelC);
            app.GridLayout2 = uigridlayout(app.PanelC2);
            app.AnalyzeButton = uibutton(app.GridLayout2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SynthesizeButton = uibutton(app.GridLayout2, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonPushed, true);
            app.SynthesizeButton.Text = 'Synthesize';
            app.PanelD = uipanel(app.Panel);
            app.PanelD.Title = 'Program Messages';
            app.PanelD1 = uipanel(app.PanelD);
            app.MsgBox = uitextarea(app.PanelD1);
            % Show the figure after all components are created
            app.DBMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARSS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBMUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBMUIFigure)
        end
    end
end